classdef Autoalign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ParallelizationMenu        matlab.ui.container.Menu
        StartparallelpoolMenu      matlab.ui.container.Menu
        CloseparallelpoolMenu      matlab.ui.container.Menu
        OptionsMenu                matlab.ui.container.Menu
        BatchprocessingMenu        matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        ratio                      matlab.ui.control.Button
        avg                        matlab.ui.control.Button
        asymmetryCalc_2            matlab.ui.control.Button
        imBDrop                    matlab.ui.control.DropDown
        ImageBLabel                matlab.ui.control.Label
        imADrop                    matlab.ui.control.DropDown
        ImageADropDownLabel        matlab.ui.control.Label
        modeRegister               matlab.ui.control.DropDown
        STDintPanel                matlab.ui.container.Panel
        GridLayout4_2              matlab.ui.container.GridLayout
        STDinterval                matlab.ui.control.NumericEditField
        TimeseriesPanel            matlab.ui.container.Panel
        EditField                  matlab.ui.control.NumericEditField
        nthfactorSpinner           matlab.ui.control.Spinner
        nthfactorSpinnerLabel      matlab.ui.control.Label
        CalcBins                   matlab.ui.control.Button
        FlatsigmaPanel             matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        flatSigma                  matlab.ui.control.NumericEditField
        RegistrationPanel          matlab.ui.container.Panel
        registrationMode           matlab.ui.control.DropDown
        TabGroup                   matlab.ui.container.TabGroup
        tabDemon                   matlab.ui.container.Tab
        iterVal                    matlab.ui.control.NumericEditField
        IterationsLabel            matlab.ui.control.Label
        smoothVal                  matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel    matlab.ui.control.Label
        pyLevel                    matlab.ui.control.NumericEditField
        PyramidlevelsLabel         matlab.ui.control.Label
        tabInt                     matlab.ui.container.Tab
        transform                  matlab.ui.control.DropDown
        SmoothingEditFieldLabel_2  matlab.ui.control.Label
        ModalityLabel              matlab.ui.control.Label
        modality                   matlab.ui.control.DropDown
        FlatfieldcorrectionButton  matlab.ui.control.Button
        SliderLabel                matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        view_imagePlaySeries       matlab.ui.control.Button
        view_imageType             matlab.ui.control.DropDown
        export                     matlab.ui.control.Button
        asymm                      matlab.ui.control.Button
        autoalign                  matlab.ui.control.Button
        import                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Initialize structure p3b
        p3b = struct('imRaw',[],'im',[],'imAligned',[],'polarization',[],'energy',[],'I0',[]);
        pathname;
        coreCount;
        imgXA;
        imgXMCD;
        imgRawXA;
        imgRawXMCD;
        convention;
        alignROI;
        XMCDSet;
        XASet;
        imgFactors;
        normMode = 'minmax';
        normSTD = 1;
        numim;
        avgSet = struct('im',[],'tag',[]);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if the parallel pool has already been initalized
            %{
            p = gcp('nocreate'); % If no pool, do not create new one.
            if isempty(p)
                app.Lamp.Color = [0.5,0.5,0.5];
            else
                app.Lamp.Color = [0,1,0];
            end
            %}
        end

        % Button pushed function: import
        function importButtonPushed(app, event)
            % Initialize structure p3b
            app.p3b = struct('imRaw',[],'im',[],'imAligned',[],'polarization',[],'energy',[],'I0',[]);
            % Import P3B files
            if ischar(app.pathname)
                [filename_m, app.pathname] = uigetfile(...
                    '*.p3b','Select P3B files','MultiSelect','on',app.pathname); 
            else
                [filename_m, app.pathname] = uigetfile(...
                    '*.p3b','Select P3B files','MultiSelect','on'); 
            end
            
            currentStatus = uiprogressdlg(app.UIFigure,'Message','Importing files.');
            if iscell(filename_m)==0 
                I = readP3B([app.pathname filename_m]);    %reads the P3B headers and data
                app.p3b(1).imRaw = reshape(I.DATA,[I.DIMX,I.DIMY])'; 
            else
                for t=1:length(filename_m)
                    filename=char(filename_m(t));
                    I = readP3B([app.pathname filename]);    %reads the P3B headers and data
                    app.p3b(t).energy = I.BEAMLINE_ENERGY;     % array for mono energy that each image is taken at
                    app.p3b(t).polarization=I.EPU_POLARIZATION;       % array for polarization that each image is taken at
                    app.p3b(t).I0=I.I0;                   % array for beamline Izero
                    app.p3b(t).imRaw=imNorm(single(reshape(I.DATA,[I.DIMX,I.DIMY])),Mode = app.modeRegister.Value,...
                        STDInterval = app.STDinterval.Value);   % 2D images stored in a 3D stack
                    currentStatus.Value = t/length(filename_m);
                end
            end
            
            % Define how many images exist in app.p3b
            app.numim = length(app.p3b);
            
            % Alter the slider limit to reflect the amount of p3b images imported
            app.Slider.Limits = [1,length(filename_m)];
            app.Slider.Value = 1;
            app.Slider.Enable = 1;
            % Alter the dropdown menu to show the raw p3b images
            app.view_imageType.Value = "Raw PEEM images";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            % Show the first image in the axes window
            p3bImShow(app,double(app.p3b(1).imRaw));
            close(currentStatus)
            % Draw a rectangular ROI in the image
            [height,width] = size(app.p3b(1).imRaw);
            app.alignROI = drawrectangle(app.UIAxes,'Deletable',false,'FaceAlpha',0.1,...
                    'MarkerSize',5,'Position',0.5*[width/2,height/2,width,height]);
        end

        % Button pushed function: FlatfieldcorrectionButton
        function FlatfieldcorrectionButtonPushed(app, event)
            % Define the flatfield sigma
            sigma = app.flatSigma.Value;
            % Set up a UI waitbar to indicate progress
            currentStatus = uiprogressdlg(app.UIFigure,'Message','Applying flatfield correction');
            % Performs flatfield correction on the image set
            for i = 1:app.numim
                %app.p3b(i).im = imNorm(imflatfield(app.p3b(i).imRaw,sigma),Mode = app.normMode,STDInterval = app.normSTD);
                app.p3b(i).im = imNorm(imflatfield(app.p3b(i).imRaw,sigma),Mode = app.modeRegister.Value,...
                        STDInterval = app.STDinterval.Value);
                currentStatus.Value = i/app.numim;
            end
            close(currentStatus);
        end

        % Callback function
        function view_imageSpinnerValueChanged(app, event)
            % Alters the image that is shown in the window; option only available for viewing image series
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    p3bImShow(app,app.p3b(app.view_imageSpinner.Value).imRaw);
                case "Aligned PEEM images"
                    p3bImShow(app,app.p3b(app.view_imageSpinner.Value).imAligned);
                case "Bin series"
                    p3bImShow(app,app.XMCDSet(app.view_imageSpinner.Value).img);
            end
        end

        % Button pushed function: view_imagePlaySeries
        function view_imagePlaySeriesButtonPushed(app, event)
            % Plays image series
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    for i = 1:app.numim; p3bImShow(app,app.p3b(i).imRaw); pause(0.01); app.Slider.Value = i; end
                case "Aligned PEEM images"
                    for i = 1:app.numim; p3bImShow(app,app.p3b(i).imAligned); pause(0.01); app.Slider.Value = i; end
                case "Flat PEEM images"
                    for i = 1:app.numim; p3bImShow(app,app.p3b(i).im); pause(0.01); app.Slider.Value = i; end
                case "Bin series"
                    [~,numel] = size(app.XMCDSet);
                    for i = 1:numel; p3bImShow(app,app.XMCDSet(i).img); pause(0.1); app.Slider.Value = i; end
                case "Average sets"
                    numel = length(app.avgSet);
                    for i = 1:numel; p3bImShow(app,app.avgSet(i).im); pause(0.1); app.Slider.Value = i; end
                    
            end
        end

        % Button pushed function: autoalign
        function autoalignButtonPushed(app, event)
            % Perform image autoaligning using image registration
            % Set up the configuration to monomodal (similar brightness/intensity)
            switch app.modality.Value
                case 'Mono'; modalityType = 'monomodal';
                case 'Multi'; modalityType = 'multimodal';
            end
            [optimizer, metric] = imregconfig(modalityType);
            % Define transformation type
            switch app.transform.Value
                case 'Translation'; tformType = 'translation';
                case 'Rigid'; tformType = 'rigid';
                case 'Similarity'; tformType = 'similarity';
                case 'Affine'; tformType = 'affine';
            end
            % Define parameters if demon mode is selected
            pyLevel = app.pyLevel.Value;
            smoothVal = app.smoothVal.Value;
            iterVal = app.iterVal.Value;
            
            % Make sure that, for the first image, we do not perform registration since all OTHER images
            % will be aligned to the first image
            app.p3b(1).imAligned = app.p3b(1).im;
            p3bAlignTo = app.p3b(1).im;
            % Save the images to be aligned into separate matrices for parfor calculation
            [width, height] = size(app.p3b(1).im);
            switch app.registrationMode.Value
                case 'Disp. demons'
                    p3bImageSet = zeros(width,height,app.numim,'single','gpuArray');
                case 'Optimize similarity'
                    p3bImageSet = zeros(width,height,app.numim,'single');
                    p3bImRaw = p3bImageSet;
                    % Make a separate matrix to store all the parfor aligned values
                    p3bImageSetAligned = zeros(size(p3bImageSet),'single');
            end
            
            % Save a separate variable to align to (solves overhead problem)
            for i = 1:app.numim
                p3bImageSet(:,:,i) = app.p3b(i).im;
                p3bImRaw(:,:,i) = app.p3b(i).imRaw;
            end
            
            parDataQ = parallel.pool.DataQueue;
            currentStatus = uiprogressdlg(app.UIFigure,'Message','Images are being aligned');
            afterEach(parDataQ, @nUpdateWaitbar);
            p = 1;

            % Get the size of p3bAlignTo
            szp3bAlignTo = size(p3bAlignTo);

            % Calculate registrations
            switch app.registrationMode.Value
                case 'Disp. demons'
                    for i = 1:app.numim
                        [tform,moving_reg] = imregdemons(p3bImageSet(:,:,i),p3bAlignTo,iterVal,...
                            "AccumulatedFieldSmoothing",smoothVal,"PyramidLevels",pyLevel,'DisplayWaitbar',false);
                        tempImage = imwarp(app.p3b(i).imRaw,tform,'linear');
                        tempImage(tempImage == 0) = NaN;
                        %moving_reg(moving_reg == 0) = NaN;
                        app.p3b(i).imAligned = tempImage;
                        send(parDataQ,i);
                    end
                case 'Optimize similarity'
                    parfor i = 1:app.numim
                        imgTemp = p3bImageSet(:,:,i);
                        imRawTemp = p3bImRaw(:,:,i);
                        % Make sure that, for the first image, we do not perform registration since all OTHER images
                        % will be aligned to the first image
                        tform = imregtform(imgTemp,p3bAlignTo,tformType,optimizer,metric);
                        p3bImageSetAligned(:,:,i) = imwarp(imRawTemp,tform,'linear',OutputView=imref2d(szp3bAlignTo));
                        send(parDataQ,i);
                    end
                    % Store values from the parfor into the structures
                    for i = 1:app.numim
                        % Set all zero regions to NaN
                        tempImage = p3bImageSetAligned(:,:,i);
                        tempImage(tempImage == 0) = NaN;
                        app.p3b(i).imAligned = tempImage;
                    end
            end
            
            
            % Display aligned images
            % Alter the slider limit to reflect the amount of p3b images imported
            %app.view_imageSpinner.Value = 1;
            %app.view_imageSpinner.Enable = 1;
            % Alter the dropdown menu to show the raw p3b images
            app.view_imageType.Value = "Aligned PEEM images";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            % Show the first image in the axes window
            p3bImShow(app,double(app.p3b(1).imAligned));
            close(currentStatus);
            % Function for the waitbar
            function nUpdateWaitbar(~)
                currentStatus.Value = p/(app.numim);
                p = p+1;
            end
        end

        % Callback function
        function multicoresButtonPushed(app, event)
            % Update the number of cores MATLAB can use for calculating the image registration
            app.coreCount = inputdlg('Enter the number of cores MATLAB can use to help speed up the alignment',...
                'Core counts');
            currentStatus = uiprogressdlg(app.UIFigure,'Message','MATLAB is initializing cores for parallel calculation.',...
                "Indeterminate","on");
            try
                parpool(str2num(app.coreCount{1})); %#ok<ST2NM> 
            catch ME
                uialert(app.UIFigure,...
                    sprintf('An error has occurred. If the error code mentions that it has found an interactive session, that means that the parpool is already activated. MATLAB has reported the following error:\n\n%s\n\nCheck function: "%s" at line %d',...
                    ME.message,ME.stack(1).name,ME.stack(1).line),'Error','Icon','error');
            end
             close(currentStatus);
        end

        % Menu selected function: CloseparallelpoolMenu
        function CloseparallelpoolMenuSelected(app, event)
            currentStatus = uiprogressdlg(app.UIFigure,'Message','MATLAB is closing the parallel pool.',...
                "Indeterminate","on");
            delete(gcp('nocreate'));
            app.Lamp.Color = [0.5,0.5,0.5];
            close(currentStatus)
        end

        % Value changed function: view_imageType
        function view_imageTypeValueChanged(app, event)
            % Enables/disables options depending on what option has been selected
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    %app.view_imageSpinner.Enable = 1;
                    %app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.p3b(1).imRaw);
                case "Flat PEEM images"
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.p3b(1).im);
                case "Aligned PEEM images"
                    %app.view_imageSpinner.Enable = 1;
                    %app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.p3b(1).imAligned);
                case "Raw XA-PEEM"
                    %app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgRawXA);
                case "Raw asymmetry"
                    %app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgRawXMCD);
                case "Flatfield XA-PEEM"
                    %app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgXA);
                case "Flatfield asymmetry"
                    %app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgXMCD);
                case "Bin series"
                    %app.view_imageSpinner.Enable = 1;
                    %app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.XMCDSet(1).img);
                case "Average sets"
                    %app.view_imageSpinner.Enable = 1;
                    %app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.avgSet(1).im);
            end
        end

        % Button pushed function: asymmetryCalc_2
        function asymmetryCalc_2ButtonPushed(app, event)
            % Averages the different image sets
            % Check what different energy values or polarizations exist in the set
            pol = unique(vertcat(app.p3b.polarization));
            hv = unique(round(vertcat(app.p3b.energy),1));
            % Create a structure containing the labels and averaged images
            app.avgSet = struct('im',[],'tag',[]);
            currentStatus = uiprogressdlg(app.UIFigure,'Message','Calculating.',...
                "Indeterminate","on");
            % Check which variable has more than 1 element and average
            if numel(pol) > 1
                for i = 1:numel(pol)
                    % Look for all indices which have the same polarizations
                    currPolIdx = find(vertcat(app.p3b.polarization) == pol(i));
                    % Get the length of the currPolIdx
                    lenPol = length(currPolIdx);
                    % Label the avgSet
                    app.avgSet(i).tag = pol(i);
                    % Average all the polarization images
                    for j = 1:lenPol 
                        idx = currPolIdx(j);
                        if j == 1; app.avgSet(i).im = app.p3b(idx).imAligned / lenPol;
                        else; app.avgSet(i).im = app.avgSet(i).im + (app.p3b(idx).imAligned / lenPol);
                        end
                    end
                end
                % Define varLst, which is the values to populate imADrop and imBDrop with
                varLst = pol;
            elseif numel(hv) > 1 % BOILERPLATE!!!!
                for i = 1:numel(hv)
                    % Look for all indices which have the same energy
                    currhvIdx = find(round(vertcat(app.p3b.energy),1) == hv(i));
                    % Get the length of the currPolIdx
                    lenhv = length(currhvIdx);
                    % Label the app.avgSet
                    app.avgSet(i).tag = hv(i);
                    % Average all the polarization images
                    for j = 1:lenhv
                        idx = currhvIdx(j);
                        if j == 1; app.avgSet(i).im = app.p3b(idx).imAligned / lenhv;
                        else; app.avgSet(i).im = app.avgSet(i).im + (app.p3b(idx).imAligned / lenhv);
                        end
                    end
                end
                % Define varLst, which is the values to populate imADrop and imBDrop with
                varLst = hv;
            end
            % Once the averages has been calculated, populate imADrop and imBDrop
            app.imADrop.Items = string(varLst);
            app.imBDrop.Items = string(varLst);
            % Set the value of the ItemsData in the dropdown menus
            app.imADrop.ItemsData = varLst;
            app.imBDrop.ItemsData = varLst;
            % 
            close(currentStatus);
        end

        % Button pushed function: asymm
        function asymmButtonPushed(app, event)
            % Perform asymmetry calculation
            app.imgRawXMCD = oponim(app,'asymm');
            app.view_imageType.Value = "Raw asymmetry";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            app.flatSigma.Enable = 1;
            p3bImShow(app,app.imgRawXMCD);
        end

        % Button pushed function: CalcBins
        function CalcBinsButtonPushed(app, event)
            % Ask the user whether they want to use RCP-LCP or LCP-RCP convention
            app.convention = uiconfirm(app.UIFigure,'Select the method you wish to perform asymmetry calculation with.',...
                'Asymmetry option','Options',{'(RCP-LCP)/Sum','(LCP-RCP)/Sum'});
            % Calculate the XMCD image sets based on the number of image divisions specified
            [~, numImages] = size(app.p3b);
            % Iteratively calculate the image sets based on the chosen factor
            numFactor = app.imgFactors(app.nthfactorSpinner.Value); % Number of images to break up into
            imgPerBin = numImages/numFactor;
            app.XMCDSet = struct('img',[]);
            app.XASet = struct('img',[]);
            for i = 1:numFactor
                idxStart = 1+imgPerBin*(i-1);
                idxEnd = imgPerBin*(i);
                [app.XMCDSet(i).img, app.XASet(i).img] = ...
                    calcAsym(app.p3b(idxStart:idxEnd),app.convention,app.flatSigma.Value,1,0.1);
            end
            
        end

        % Value changed function: nthfactorSpinner
        function nthfactorSpinnerValueChanged(app, event)
            % Change display of the chosen factor
            app.EditField.Value = app.imgFactors(app.nthfactorSpinner.Value);
        end

        % Button pushed function: export
        function exportButtonPushed(app, event)
            exportPEEM(app);
        end

        % Menu selected function: StartparallelpoolMenu
        function StartparallelpoolMenuSelected(app, event)
            % Update the number of cores MATLAB can use for calculating the image registration
            app.coreCount = inputdlg('Enter the number of cores MATLAB can use to help speed up the alignment',...
                'Core counts');
            currentStatus = uiprogressdlg(app.UIFigure,'Message','MATLAB is initializing cores for parallel calculation.',...
                "Indeterminate","on");
            try
                parpool(str2num(app.coreCount{1})); %#ok<ST2NM> 
                app.Lamp.Color = [0,1,0];
            catch ME
                uialert(app.UIFigure,...
                    sprintf('An error has occurred. If the error code mentions that it has found an interactive session, that means that the parpool is already activated. MATLAB has reported the following error:\n\n%s\n\nCheck function: "%s" at line %d',...
                    ME.message,ME.stack(1).name,ME.stack(1).line),'Error','Icon','error');
            end
             close(currentStatus);
        end

        % Menu selected function: BatchprocessingMenu
        function BatchprocessingMenuSelected(app, event)
            % Looks at a directory. Then processes all the images within the subdirectories.
            % Ask user to select the directory
            if ischar(app.pathname)
                app.pathname = uigetdir(app.pathname,'Select P3B files'); 
            else
                app.pathname = uigetdir('Select P3B files'); 
            end
            
            % Store the location of all the subdirectories in subD
            d = dir(app.pathname);
            subD = d([d(:).isdir]);
            subD = subD(~ismember({subD(:).name},{'.','..'}));
            numSubD = length(subD); % Total number of subdirectories

            % For alignment, set up the configuration to monomodal (similar brightness/intensity)
            [optimizer, metric] = imregconfig('monomodal');

            % Set up the convention for calculating XMCD 
            app.convention = '(RCP-LCP)/Sum';

            % Open dialog to show analysis progress
            currentStatus = uiprogressdlg(app.UIFigure,'Message','Performing batch process...');

            % Run a large for loop to cycle through each and every subdirectory
            for i = 1:numSubD
                % Get a list of everything in subdirectory
                fileList = dir(fullfile(subD(i).folder,subD(i).name));
                % Look for all files with the extension p3b
                [~,~,extension] = fileparts({fileList.name});
                % Convert extension from a char cell array to a string matrix
                extension = convertCharsToStrings(extension);
                % Identify the entries that are ".P3B"
                isP3B = find(strcmp(extension,".P3B"));
                % Determine the total number of P3B files to import
                numel = length(isP3B);
                % Set the primary file name to save as
                file = subD(i).name;
                % Set the path to save the file to
                path = app.pathname;

                % Initialize the app.p3b structure
                app.p3b = struct('imRaw',[],'imAligned',[],'polarization',[],'energy',[],'I0',[]);

                % Start importing the files
                for j = 1:numel
                    % Look at the n-th P3B file in the subdirectory
                    idx = isP3B(j);
                    % Look at the corresponding file name
                    fileName = fileList(idx).name;
                    % Use readP3B function to, well... read the P3B file.
                    I = readP3B(fullfile(app.pathname, file,fileName));
                    % Store the parameters in I to the app.p3b structure
                    app.p3b(j).energy = I.BEAMLINE_ENERGY;     % array for mono energy that each image is taken at
                    app.p3b(j).polarization=I.EPU_POLARIZATION;       % array for polarization that each image is taken at
                    app.p3b(j).I0=I.I0;                   % array for beamline Izero
                    app.p3b(j).imRaw=reshape(I.DATA,[I.DIMX,I.DIMY]);   % 2D images stored in a 3D stack
                end

                % Make sure that, for the first image, we do not perform registration since all OTHER images
                % will be aligned to the first image
                app.p3b(1).imAligned = app.p3b(1).imRaw;
                imgMean = mean(app.p3b(1).imRaw,'all');
                imgSTD = std(app.p3b(1).imRaw,0,'all');
                p3bAlignTo = imNorm(app.p3b(1).imRaw,Mode = app.modeRegister.Value,...
                        STDInterval = app.STDinterval.Value);
                % Save the images to be aligned into separate matrices for parfor calculation
                [width, height] = size(app.p3b(1).imRaw);
                p3bImageSet = zeros(width,height,numel);

                % Save a separate variable to align to (solves overhead problem)
                for k = 1:numel
                    p3bImageSet(:,:,k) = app.p3b(k+1).imRaw;
                end
                % Make a separate matrix to store all the parfor aligned values
                p3bImageSetAligned = zeros(size(p3bImageSet));
                % Calculate alignments
                parfor k = 1:numel
                    imgMean = mean(p3bImageSet(:,:,k),'all');
                    imgSTD = std(p3bImageSet(:,:,k),0,'all');
                    imgTemp = p3bImageSet(:,:,k);
                    imgTempReg = imNorm(imgTemp,Mode = app.modeRegister.Value,...
                        STDInterval = app.STDinterval.Value);
                    % Make sure that, for the first image, we do not perform registration since all OTHER images
                    % will be aligned to the first image
                    tform = imregtform(imgTempReg,p3bAlignTo,'translation',optimizer,metric);
                    p3bImageSetAligned(:,:,k) = imwarp(imgTemp,tform,'OutputView',imref2d(size(p3bAlignTo)));
                end
                
                % Store values from the parfor into the structures
                for k = 1:numel
                    % Set all zero regions to NaN
                    tempImage = p3bImageSetAligned(:,:,k);
                    tempImage(tempImage == 0) = NaN;
                    app.p3b(k).imAligned = tempImage;
                end

                % Average images based on polarization
                [width,height] = size(app.p3b(1).imAligned);
                [~,numImages] = size(app.p3b);
                imgRCPRaw = zeros(width,height);
                imgLCPRaw = imgRCPRaw;
                imgLCPFlat = imgRCPRaw;
                imgRCPFlat = imgRCPRaw;
                for k = 1:numImages
                    % Bullshit fix: since imflatfield doesn't like NaN, save a separate image that has the nan cropped out
                    A = app.p3b(k).imAligned;
                    C = A;
                    C(all(isnan(C),2),:) = [];
                    C(:,all(isnan(C),1)) = [];
                    C = imflatfield(C,app.flatSigma.Value);
                    % Save flatfield-corrected images
                    B = app.p3b(k).imAligned;
                    B(~isnan(B)) = 1;
                    B(B==1) = C;
                    if app.p3b(k).polarization == 0.9
                        imgRCPRaw = imgRCPRaw + A;
                        imgRCPFlat = imgRCPFlat + B;
                    elseif app.p3b(k).polarization == -0.9
                        imgLCPRaw = imgLCPRaw + A;
                        imgLCPFlat = imgLCPFlat + B;
                    end
                end
                
                % Save XAS image
                app.imgRawXA = (imgRCPRaw + imgLCPRaw) / 2;
                app.imgXA = (imgRCPFlat + imgLCPFlat) / 2;

                % Save XMCD image (flattened)
                app.imgRawXMCD = (imgRCPRaw - imgLCPRaw)./(imgRCPRaw + imgLCPRaw);
                app.imgXMCD = (imgRCPFlat - imgLCPFlat)./(imgRCPFlat + imgLCPFlat);

                % Write raw XA- and XMCD-PEEM images
                A = app.imgRawXA';
                A(all(isnan(A),2),:) = [];
                A(1:10,:) = [];
                A(end-10:end,:) = [];
                A(:,all(isnan(A),1)) = [];
                A(:,1:10) = [];
                A(:,end-10:end) = [];
                t = Tiff(fullfile(path,strcat('AVG_',file)),'w');
                tagstruct.ImageLength     = size(A,1);
                tagstruct.ImageWidth      = size(A,2);
                tagstruct.Photometric     = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample   = 32;
                tagstruct.SamplesPerPixel = 1;
                %tagstruct.RowsPerStrip    = 16;
                tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software        = 'MATLAB';
                t.setTag(tagstruct);
                t.write(rot90(flip(double(A),2),2));
                t.close();
                
                A = app.imgRawXMCD';
                A(all(isnan(A),2),:) = [];
                A(1:10,:) = [];
                A(end-10:end,:) = [];
                A(:,all(isnan(A),1)) = [];
                A(:,1:10) = [];
                A(:,end-10:end) = [];
                t = Tiff(fullfile(path,strcat('Asymm_',file)),'w');
                tagstruct.ImageLength     = size(A,1);
                tagstruct.ImageWidth      = size(A,2);
                tagstruct.Photometric     = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample   = 32;
                tagstruct.SamplesPerPixel = 1;
                %tagstruct.RowsPerStrip    = 16;
                tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software        = 'MATLAB';
                t.setTag(tagstruct);
                t.write(rot90(flip(double(A),2),2));
                t.close();
                
                % Write flatfield XA- and XMCD-PEEM images
                A = app.imgXA';
                A(all(isnan(A),2),:) = [];
                A(1:10,:) = [];
                A(end-10:end,:) = [];
                A(:,all(isnan(A),1)) = [];
                A(:,1:10) = [];
                A(:,end-10:end) = [];
                t = Tiff(fullfile(path,strcat('Flat_AVG_',file)),'w');
                tagstruct.ImageLength     = size(A,1);
                tagstruct.ImageWidth      = size(A,2);
                tagstruct.Photometric     = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample   = 32;
                tagstruct.SamplesPerPixel = 1;
                %tagstruct.RowsPerStrip    = 16;
                tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software        = 'MATLAB';
                t.setTag(tagstruct);
                t.write(rot90(flip(double(A),2),2));
                t.close();
                
                A = app.imgXMCD';
                A(all(isnan(A),2),:) = [];
                A(1:10,:) = [];
                A(end-10:end,:) = [];
                A(:,all(isnan(A),1)) = [];
                A(:,1:10) = [];
                A(:,end-10:end) = [];
                t = Tiff(fullfile(path,strcat('Flat_Asymm_',file)),'w');
                tagstruct.ImageLength     = size(A,1);
                tagstruct.ImageWidth      = size(A,2);
                tagstruct.Photometric     = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample   = 32;
                tagstruct.SamplesPerPixel = 1;
                %tagstruct.RowsPerStrip    = 16;
                tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software        = 'MATLAB';
                t.setTag(tagstruct);
                t.write(rot90(flip(double(A),2),2));
                t.close();
                currentStatus.Value = i/(numSubD);
            end

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % Alters the image that is shown in the window; option only available for viewing image series
            i = floor(app.Slider.Value);
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    p3bImShow(app,double(app.p3b(i).imRaw));
                case "Aligned PEEM images"
                    p3bImShow(app,double(app.p3b(i).imAligned));
                case "Bin series"
                    p3bImShow(app,double(app.XMCDSet(i).img));
            end
            % Change the slider label value
            app.SliderLabel.Text = sprintf('%d',i);
        end

        % Value changed function: registrationMode
        function registrationModeValueChanged(app, event)
            % Switches the tab from demon mode operation to intensity mode operation
            switch app.registrationMode.Value
                case 'Disp. demons'; app.TabGroup.SelectedTab = app.tabDemon;
                case 'Optimize similarity'; app.TabGroup.SelectedTab = app.tabInt;
            end
        end

        % Button pushed function: avg
        function avgButtonPushed(app, event)
            % Perform asymmetry calculation
            app.imgRawXMCD = oponim(app,'avg');
            app.view_imageType.Value = "Raw asymmetry";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            app.flatSigma.Enable = 1;
            p3bImShow(app,app.imgRawXMCD);
        end

        % Button pushed function: ratio
        function ratioButtonPushed(app, event)
            % Perform asymmetry calculation
            app.imgRawXMCD = oponim(app,'ratio');
            app.view_imageType.Value = "Raw asymmetry";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            app.flatSigma.Enable = 1;
            p3bImShow(app,app.imgRawXMCD);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'P3B Auto-Aligner';
            app.ParallelizationMenu = uimenu(app.UIFigure);
            app.ParallelizationMenu.Text = 'Parallelization';
            app.StartparallelpoolMenu = uimenu(app.ParallelizationMenu);
            app.StartparallelpoolMenu.MenuSelectedFcn = createCallbackFcn(app, @StartparallelpoolMenuSelected, true);
            app.StartparallelpoolMenu.Text = 'Start parallel pool';
            app.CloseparallelpoolMenu = uimenu(app.ParallelizationMenu);
            app.CloseparallelpoolMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseparallelpoolMenuSelected, true);
            app.CloseparallelpoolMenu.Text = 'Close parallel pool';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.BatchprocessingMenu = uimenu(app.OptionsMenu);
            app.BatchprocessingMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchprocessingMenuSelected, true);
            app.BatchprocessingMenu.Text = 'Batch processing';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.import = uibutton(app.GridLayout, 'push');
            app.import.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.import.Text = 'Import P3B files';
            app.autoalign = uibutton(app.GridLayout, 'push');
            app.autoalign.ButtonPushedFcn = createCallbackFcn(app, @autoalignButtonPushed, true);
            app.autoalign.Text = 'Autoalign images';
            app.asymm = uibutton(app.GridLayout, 'push');
            app.asymm.ButtonPushedFcn = createCallbackFcn(app, @asymmButtonPushed, true);
            app.asymm.Text = '(A-B)/(A+B)';
            app.export = uibutton(app.GridLayout, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.export.Text = 'Export';
            app.view_imageType = uidropdown(app.GridLayout);
            app.view_imageType.Items = {'Raw PEEM images', 'Flat PEEM images', 'Aligned PEEM images', 'Average sets', 'Raw XA-PEEM', 'Raw asymmetry', 'Flatfield XA-PEEM', 'Flatfield asymmetry', 'Bin series', 'XAS series'};
            app.view_imageType.ValueChangedFcn = createCallbackFcn(app, @view_imageTypeValueChanged, true);
            app.view_imageType.Enable = 'off';
            app.view_imageType.Value = 'Raw PEEM images';
            app.view_imagePlaySeries = uibutton(app.GridLayout, 'push');
            app.view_imagePlaySeries.ButtonPushedFcn = createCallbackFcn(app, @view_imagePlaySeriesButtonPushed, true);
            app.view_imagePlaySeries.Enable = 'off';
            app.view_imagePlaySeries.Text = ' ▶';
            app.Slider = uislider(app.GridLayout);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = '1';
            app.FlatfieldcorrectionButton = uibutton(app.GridLayout, 'push');
            app.FlatfieldcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @FlatfieldcorrectionButtonPushed, true);
            app.FlatfieldcorrectionButton.Text = 'Flatfield correction';
            app.RegistrationPanel = uipanel(app.GridLayout);
            app.RegistrationPanel.Title = 'Registration';
            app.TabGroup = uitabgroup(app.RegistrationPanel);
            app.tabDemon = uitab(app.TabGroup);
            app.PyramidlevelsLabel = uilabel(app.tabDemon);
            app.PyramidlevelsLabel.Text = 'Pyramid levels:';
            app.pyLevel = uieditfield(app.tabDemon, 'numeric');
            app.pyLevel.Limits = [1 Inf];
            app.pyLevel.RoundFractionalValues = 'on';
            app.pyLevel.Value = 3;
            app.SmoothingEditFieldLabel = uilabel(app.tabDemon);
            app.SmoothingEditFieldLabel.Text = 'Smoothing:';
            app.smoothVal = uieditfield(app.tabDemon, 'numeric');
            app.smoothVal.Limits = [0 Inf];
            app.smoothVal.Value = 1;
            app.IterationsLabel = uilabel(app.tabDemon);
            app.IterationsLabel.Text = 'Iterations:';
            app.iterVal = uieditfield(app.tabDemon, 'numeric');
            app.iterVal.Limits = [1 Inf];
            app.iterVal.RoundFractionalValues = 'on';
            app.iterVal.Value = 100;
            app.tabInt = uitab(app.TabGroup);
            app.modality = uidropdown(app.tabInt);
            app.modality.Items = {'Mono', 'Multi'};
            app.modality.Value = 'Mono';
            app.ModalityLabel = uilabel(app.tabInt);
            app.ModalityLabel.Text = 'Modality:';
            app.SmoothingEditFieldLabel_2 = uilabel(app.tabInt);
            app.SmoothingEditFieldLabel_2.Text = 'Mode:';
            app.transform = uidropdown(app.tabInt);
            app.transform.Items = {'Translation', 'Rigid', 'Similarity', 'Affine'};
            app.transform.Value = 'Translation';
            app.registrationMode = uidropdown(app.RegistrationPanel);
            app.registrationMode.Items = {'Disp. demons', 'Optimize similarity'};
            app.registrationMode.ValueChangedFcn = createCallbackFcn(app, @registrationModeValueChanged, true);
            app.registrationMode.Value = 'Disp. demons';
            app.FlatsigmaPanel = uipanel(app.GridLayout);
            app.FlatsigmaPanel.Title = 'Flat sigma';
            app.GridLayout4 = uigridlayout(app.FlatsigmaPanel);
            app.flatSigma = uieditfield(app.GridLayout4, 'numeric');
            app.flatSigma.Value = 100;
            app.TimeseriesPanel = uipanel(app.GridLayout);
            app.TimeseriesPanel.Title = 'Time series';
            app.CalcBins = uibutton(app.TimeseriesPanel, 'push');
            app.CalcBins.ButtonPushedFcn = createCallbackFcn(app, @CalcBinsButtonPushed, true);
            app.CalcBins.Text = 'Calc';
            app.nthfactorSpinnerLabel = uilabel(app.TimeseriesPanel);
            app.nthfactorSpinnerLabel.Text = 'n-th factor';
            app.nthfactorSpinner = uispinner(app.TimeseriesPanel);
            app.nthfactorSpinner.Limits = [1 Inf];
            app.nthfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @nthfactorSpinnerValueChanged, true);
            app.nthfactorSpinner.Value = 1;
            app.EditField = uieditfield(app.TimeseriesPanel, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Editable = 'off';
            app.EditField.Value = 1;
            app.STDintPanel = uipanel(app.GridLayout);
            app.STDintPanel.Title = 'STD int.';
            app.GridLayout4_2 = uigridlayout(app.STDintPanel);
            app.STDinterval = uieditfield(app.GridLayout4_2, 'numeric');
            app.STDinterval.Limits = [0 Inf];
            app.STDinterval.Value = 1;
            app.modeRegister = uidropdown(app.GridLayout);
            app.modeRegister.Items = {'minmax', 'mean'};
            app.modeRegister.Value = 'minmax';
            app.ImageADropDownLabel = uilabel(app.GridLayout);
            app.ImageADropDownLabel.Text = 'Image A';
            app.imADrop = uidropdown(app.GridLayout);
            app.imADrop.Items = {};
            app.imADrop.Value = {};
            app.ImageBLabel = uilabel(app.GridLayout);
            app.ImageBLabel.Text = 'Image B';
            app.imBDrop = uidropdown(app.GridLayout);
            app.imBDrop.Items = {};
            app.imBDrop.Value = {};
            app.asymmetryCalc_2 = uibutton(app.GridLayout, 'push');
            app.asymmetryCalc_2.ButtonPushedFcn = createCallbackFcn(app, @asymmetryCalc_2ButtonPushed, true);
            app.asymmetryCalc_2.Text = 'Average sets';
            app.avg = uibutton(app.GridLayout, 'push');
            app.avg.ButtonPushedFcn = createCallbackFcn(app, @avgButtonPushed, true);
            app.avg.Text = '(A+B)/2';
            app.ratio = uibutton(app.GridLayout, 'push');
            app.ratio.ButtonPushedFcn = createCallbackFcn(app, @ratioButtonPushed, true);
            app.ratio.Text = 'A/B';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autoalign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end